classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ECG_time                        matlab.ui.control.UIAxes
        Switch                          matlab.ui.control.Switch
        LowpassFrequencyLabel           matlab.ui.control.Label
        LowpassFrequencyEditField       matlab.ui.control.NumericEditField
        HighpassFrequencyEditFieldLabel  matlab.ui.control.Label
        HighpassFrequencyEditField      matlab.ui.control.NumericEditField
        ECG_plot                        matlab.ui.control.UIAxes
        BPMSwitchLabel                  matlab.ui.control.Label
        BPMSwitch                       matlab.ui.control.Switch
        SaveDataSwitchLabel             matlab.ui.control.Label
        SaveDataSwitch                  matlab.ui.control.Switch
        PleaseTurnONLabel_2             matlab.ui.control.Label
        BPMLabel                        matlab.ui.control.Label
        ChooseOneforSecondGraphDropDownLabel  matlab.ui.control.Label
        ChooseOneforSecondGraphDropDown  matlab.ui.control.DropDown
        SamplingFrequencyLabel          matlab.ui.control.Label
        PleaseTurnONLabel               matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        OrderoftheFilterEditFieldLabel  matlab.ui.control.Label
        OrderoftheFilterEditField       matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
    end

    
    properties (Access = public)
        ECG_first = zeros(1,8000);
        ecg = zeros(1,2000);
        ECG_second = zeros(1,400);
        ECG_Cheby = zeros(1,8000);
        ECG_Butter = zeros(1,8000);
        ECG_Butter1 = [];
        ECG_Ellip = zeros(1,8000);
        ECG_Cheby_FFT = zeros(1,8000);
        ECG_Butter_FFT = zeros(1,8000);
        ECG_Ellip_FFT = zeros(1,8000);
        ECG_Filter = [];
        originalfft = zeros(1,8000);
        ECG_BPM = [];
        BOS = [];
        BPM_Number2 = [];
        BPM_Number1;
        v;
        n;
        s;
        t;
        d;
        zaman;
        fs;
        fn;
        freq;
        N;
        lowpassFreq = 20;
        highpassFreq = 1;
        choose_value;
        G;
        p = [];
        orderValue = 1;
    end
    
    methods (Access = public)
        
        
        function starting(app)
            for i=1:400
                app.ECG_first (i) = str2double(fgetl(app.s));
            end
         end
        
        function sliding(app)
            for j=1:400
                app.ECG_second (j) = str2double(fgetl(app.s));                
            end
           if app.v < 20
                app.ECG_first(app.n:app.n+399)=app.ECG_second(1:400);
                app.v = app.v + 1;
                app.n = app.n + 400;
          else
                
              app.ECG_first(1:(end-400)) = app.ECG_first(401:end);
                
              app.ECG_first((end-399):end) = app.ECG_second(1:400);
                
           end
            
            app.ecg = app.ECG_first-mean(app.ECG_first);
            plot(app.ECG_time, app.ECG_first,'w');drawnow;
            
            app.fs= (400)/((app.zaman));% % fs is the sampling time
                
            app.fn= app.fs/2; % fn is Nyquist frequency
                
            app.N= length(app.ecg);
                
            app.t= (0: length(app.ecg)-1)/app.fs;
                
            app.freq = linspace(0,app.fs,length(app.ecg));% app.fs değeri arttırdıkça olacak gibi
            
                        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% for BPM %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           if length(app.ECG_BPM) < 24000
           app.ECG_BPM = [app.ECG_BPM, app.ECG_second];    
           else
            app.ECG_BPM(1:(end-400)) = app.ECG_BPM(401:end);
                
            app.ECG_BPM((end-399):end) = app.ECG_second(1:400);
           end            
           [ba, ab] = butter(2,[1 20]/(app.fn),'bandpass');
           app.ECG_Filter = filter(ba, ab, app.ECG_BPM);           
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Butter Filter %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
           [b, a] = butter(app.orderValue, [app.highpassFreq app.lowpassFreq]/(app.fn),'bandpass');
           app.ECG_Butter = filter(b, a, app.ecg);
           
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Chebyshev Filter %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
           [b_cheby, a_cheby] = cheby1(app.orderValue, 0.5, [app.highpassFreq app.lowpassFreq]/(app.fn), 'bandpass');
           app.ECG_Cheby = filter(b_cheby, a_cheby, app.ecg);
           
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Elliptical Filter %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
           [b_ellip, a_ellip] = ellip(app.orderValue, 0.5, 40, [app.highpassFreq app.lowpassFreq]/(app.fn), 'bandpass');
           app.ECG_Ellip = filter(b_ellip, a_ellip, app.ecg);
           
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Original FFT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
           app.originalfft= fft(app.ecg);
           
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Butter FFT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
           app.ECG_Butter_FFT = fft(app.ECG_Butter);
           
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Cheby FFT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
           app.ECG_Cheby_FFT = fft(app.ECG_Cheby);
           
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Ellip FFT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
           app.ECG_Ellip_FFT = fft(app.ECG_Ellip);
           
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           if app.choose_value == 1
               plot(app.ECG_plot, app.ECG_Butter);xlim(app.ECG_plot,[0 8000]);drawnow;
            elseif app.choose_value == 2
                plot(app.ECG_plot, app.ECG_Cheby,'g');xlim(app.ECG_plot,[0 8000]);drawnow;
            elseif app.choose_value == 3
                plot(app.ECG_plot, app.ECG_Ellip,'r');xlim(app.ECG_plot,[0 8000]);drawnow;
            elseif app.choose_value == 4
                plot(app.ECG_plot, app.freq, abs(app.originalfft),'w');xlim(app.ECG_plot,[0 150]);drawnow; 
            elseif app.choose_value == 5
                plot(app.ECG_plot, app.freq, abs(app.ECG_Butter_FFT));xlim(app.ECG_plot,[0 150]);drawnow; 
            elseif app.choose_value == 6
                plot(app.ECG_plot, app.freq, abs(app.ECG_Cheby_FFT),'g');xlim(app.ECG_plot,[0 150]);drawnow;   
            elseif app.choose_value == 7
                plot(app.ECG_plot, app.freq, abs(app.ECG_Ellip_FFT),'r');xlim(app.ECG_plot,[0 150]);drawnow;
           elseif app.choose_value == 8
               ECG_Filter1 = app.ECG_Filter.^5;
               plot(app.ECG_plot, ECG_Filter1);drawnow;
            end


        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "On"
                                
                delete(instrfind);
                app.s = serial('COM6',"BaudRate",38400);
                fopen(app.s);
                
                app.starting;
                
                app.t =@() app.starting;
                app.zaman = timeit(app.t);
                
                app.v=1;
                app.n=401;
                
                while value == "On"
                
                app.sliding;
                
                app.ChooseOneforSecondGraphDropDown;
                app.LowpassFrequencyEditFieldValueChanged;
                app.HighpassFrequencyEditFieldValueChanged;
                app.OrderoftheFilterEditFieldValueChanged;
                if length(app.ECG_BPM) == 24000
                   app.Label_2.Text = 'Ready for BPM'; 
                else
                   app.Label_2.Text = 'NOT-Ready for BPM';  
                end
                end
            else
                app.ECG_first = zeros(1,8000);        
                app.ecg = zeros(1,2000);        
                app.ECG_second = zeros(1,400);        
                app.ECG_Cheby = zeros(1,8000);        
                app.ECG_Butter = zeros(1,8000);        
                app.ECG_Butter1 = [];        
                app.ECG_Ellip = zeros(1,8000);        
                app.ECG_Cheby_FFT = zeros(1,8000);        
                app.ECG_Butter_FFT = zeros(1,8000);        
                app.ECG_Ellip_FFT = zeros(1,8000);        
                app.ECG_Filter = [];      
                app.originalfft = zeros(1,8000);        
                app.ECG_BPM = [];
                cla(app.ECG_time);
                fclose(app.s);
                delete(instrfind);
            end
        end

        % Value changed function: HighpassFrequencyEditField
        function HighpassFrequencyEditFieldValueChanged(app, event)
            app.highpassFreq = app.HighpassFrequencyEditField.Value;
        end

        % Value changed function: LowpassFrequencyEditField
        function LowpassFrequencyEditFieldValueChanged(app, event)
            app.lowpassFreq = app.LowpassFrequencyEditField.Value;
        end

        % Value changed function: BPMSwitch
        function BPMSwitchValueChanged(app, event)
            value = app.BPMSwitch.Value;
              if value == "On"
              app.BPM_Number2 = [];
              samp = num2str(app.fs);
              app.PleaseTurnONLabel.Text = samp;
                  
                  if length(app.ECG_Filter) < 24000
                      
             app.PleaseTurnONLabel_2.Text = 'LOADING';
                      
                  else
                      
%               BPM_Number1 = app.ECG_Filter.^3;
%               q = length(BPM_Number1);
%               th= 0.04*max(BPM_Number1);
%               for as = 1:length(BPM_Number1)
%                 if BPM_Number1(as) < th
%                     BPM_Number1(as) = 0;
%                 end
%               end
BPM_Number99 = 0;
gf = 1;
                for af = 1:24
                    app.BPM_Number1(1:1000) = app.ECG_Filter(gf:(gf+999)).^3;
        
                    th= 0.5*max(app.BPM_Number1);       
        
                    for kn = 1:length(app.BPM_Number1)
        
                     if app.BPM_Number1(kn) < th
                        app.BPM_Number1(kn) = 0;
                     end
        
                     end
                        %app.BPM_Number2 = [app.BPM_Number2, app.BPM_Number1];
                     BPM_Number = findpeaks(app.BPM_Number1);
                     BPM_Number99 = BPM_Number99 + length(BPM_Number); 


                        gf = gf + 1000;
                 end
              
              %q = length(app.ECG_Filter);
              %BPM_Number = findpeaks(app.BPM_Number2);
              %BPM_str = num2str(length(BPM_Number));
              
              BPM_str = num2str(round((BPM_Number99*60)/(24000/app.fs)));
              app.PleaseTurnONLabel_2.Text = BPM_str;
              
                  end    
              else 
              app.PleaseTurnONLabel_2.Text = 'Please Turn ON';
              end
        end

        % Value changed function: ChooseOneforSecondGraphDropDown
        function ChooseOneforSecondGraphDropDownValueChanged(app, event)
            value = app.ChooseOneforSecondGraphDropDown.Value;
            if value == "Butterworth Filtered Signal"
               app.choose_value = 1;
            elseif value == "Chebyshev Filtered Signal"
               app.choose_value = 2;
            elseif value == "Elliptical Filtered Signal"
                app.choose_value = 3;
            elseif value == "Original Signal FFT"
                app.choose_value = 4;
            elseif value == "Butterworth Filtered Signal FFT"
                app.choose_value = 5;
            elseif value == "Chebyshev Filtered Signal FFT"
                app.choose_value = 6;
            elseif value == "Elliptical Filtered Signal FFT"
                app.choose_value = 7;
            elseif value == "Choose One"
                app.choose_value = 8;
            end
        end

        % Value changed function: OrderoftheFilterEditField
        function OrderoftheFilterEditFieldValueChanged(app, event)
            value = app.OrderoftheFilterEditField.Value;
            app.orderValue = value;
        end

        % Value changed function: SaveDataSwitch
        function SaveDataSwitchValueChanged(app, event)
            value = app.SaveDataSwitch.Value;
            if value == "On"
                assignin("caller","app")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ECG_time = uiaxes(app.UIFigure);
            title(app.ECG_time, 'Time Domain')
            xlabel(app.ECG_time, 'ECG')
            ylabel(app.ECG_time, 'Amplitude')
            app.ECG_time.XTick = [0 1000 2000 3000 4000 5000 6000 7000 8000];
            app.ECG_time.XTickLabel = {'0'; '1000'; '2000'; '3000'; '4000'; '5000'; '6000'; '7000'; '8000'};
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.LowpassFrequencyLabel = uilabel(app.UIFigure);
            app.LowpassFrequencyLabel.Text = 'Lowpass Frequency ';
            app.LowpassFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowpassFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @LowpassFrequencyEditFieldValueChanged, true);
            app.LowpassFrequencyEditField.Value = 20;
            app.HighpassFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.HighpassFrequencyEditFieldLabel.Text = 'Highpass Frequency';
            app.HighpassFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighpassFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @HighpassFrequencyEditFieldValueChanged, true);
            app.HighpassFrequencyEditField.Value = 1;
            app.ECG_plot = uiaxes(app.UIFigure);
            title(app.ECG_plot, 'Graph')
            xlabel(app.ECG_plot, 'ECG')
            ylabel(app.ECG_plot, 'Amplitude')
            app.BPMSwitchLabel = uilabel(app.UIFigure);
            app.BPMSwitchLabel.Text = 'BPM';
            app.BPMSwitch = uiswitch(app.UIFigure, 'slider');
            app.BPMSwitch.ValueChangedFcn = createCallbackFcn(app, @BPMSwitchValueChanged, true);
            app.SaveDataSwitchLabel = uilabel(app.UIFigure);
            app.SaveDataSwitchLabel.Text = 'Save Data';
            app.SaveDataSwitch = uiswitch(app.UIFigure, 'slider');
            app.SaveDataSwitch.ValueChangedFcn = createCallbackFcn(app, @SaveDataSwitchValueChanged, true);
            app.PleaseTurnONLabel_2 = uilabel(app.UIFigure);
            app.PleaseTurnONLabel_2.Text = 'Please Turn ON';
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = ' BPM     = ';
            app.ChooseOneforSecondGraphDropDownLabel = uilabel(app.UIFigure);
            app.ChooseOneforSecondGraphDropDownLabel.Text = 'Choose One for Second Graph';
            app.ChooseOneforSecondGraphDropDown = uidropdown(app.UIFigure);
            app.ChooseOneforSecondGraphDropDown.Items = {'Choose One', 'Butterworth Filtered Signal', 'Chebyshev Filtered Signal', 'Elliptical Filtered Signal', 'Original Signal FFT', 'Butterworth Filtered Signal FFT', 'Chebyshev Filtered Signal FFT', 'Elliptical Filtered Signal FFT'};
            app.ChooseOneforSecondGraphDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseOneforSecondGraphDropDownValueChanged, true);
            app.ChooseOneforSecondGraphDropDown.Value = 'Choose One';
            app.SamplingFrequencyLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyLabel.Text = 'Sampling Frequency   = ';
            app.PleaseTurnONLabel = uilabel(app.UIFigure);
            app.PleaseTurnONLabel.Text = 'Please Turn ON';
            app.Label = uilabel(app.UIFigure);
            app.OrderoftheFilterEditFieldLabel = uilabel(app.UIFigure);
            app.OrderoftheFilterEditFieldLabel.Text = 'Order of the Filter';
            app.OrderoftheFilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrderoftheFilterEditField.Limits = [0 Inf];
            app.OrderoftheFilterEditField.ValueChangedFcn = createCallbackFcn(app, @OrderoftheFilterEditFieldValueChanged, true);
            app.OrderoftheFilterEditField.Value = 2;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end